classdef ConnectionConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConnectionSettingsUIFigure      matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        UseProgressMonitor              matlab.ui.control.DropDown
        UseProgressMonitorDropDownLabel  matlab.ui.control.Label
        UseProxy                        matlab.ui.control.DropDown
        UseProxyDropDownLabel           matlab.ui.control.Label
        ConnectTimeout                  matlab.ui.control.NumericEditField
        ConnectionTimeoutEditFieldLabel  matlab.ui.control.Label
        MaxRedirects                    matlab.ui.control.NumericEditField
        MaximumRedirectsEditFieldLabel  matlab.ui.control.Label
        Debug                           matlab.ui.control.DropDown
        DebugDropDownLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        Connection % Description
    end
    
    properties (Access = private)
        CallingApp        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, conn)
            app.CallingApp = mainapp;
            app.Connection = conn;
            
            props = properties(app.Connection.ConnectionProperties);
            
            app.Debug.Value = app.Connection.ConnectionProperties.Debug;
            
            for p = props'

                try
                    app.(p{1}).Value = app.Connection.ConnectionProperties.(p{1});
                catch

                end

            end
            
        end

        % Close request function: ConnectionSettingsUIFigure
        function ConnectionSettingsUIFigureCloseRequest(app, event)
            app.CallingApp.ConnectionSettingsMenu.Enable = 'on';
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            props = properties(app.Connection.ConnectionProperties);
            
            app.Connection.setConnectionOptions('Debug', app.Debug.Value);
            
            for p = props'

                try
                    app.Connection.setConnectionOptions(p{1}, app.(p{1}).Value);
                catch

                end

            end
            
            app.CallingApp.ConnectionSettingsMenu.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConnectionSettingsUIFigure = uifigure('Visible', 'off');
            app.ConnectionSettingsUIFigure.Name = 'Connection Settings';
            app.ConnectionSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConnectionSettingsUIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.ConnectionSettingsUIFigure);
            app.DebugDropDownLabel = uilabel(app.GridLayout2);
            app.DebugDropDownLabel.Text = 'Debug';
            app.Debug = uidropdown(app.GridLayout2);
            app.Debug.Items = {'No', 'INFO', 'TRACE'};
            app.Debug.ItemsData = [0 1 2];
            app.Debug.Tag = 'Debug';
            app.Debug.Value = 0;
            app.MaximumRedirectsEditFieldLabel = uilabel(app.GridLayout2);
            app.MaximumRedirectsEditFieldLabel.Text = 'Maximum Redirects';
            app.MaxRedirects = uieditfield(app.GridLayout2, 'numeric');
            app.MaxRedirects.Tag = 'MaxRedirects';
            app.ConnectionTimeoutEditFieldLabel = uilabel(app.GridLayout2);
            app.ConnectionTimeoutEditFieldLabel.Text = 'Connection Timeout';
            app.ConnectTimeout = uieditfield(app.GridLayout2, 'numeric');
            app.ConnectTimeout.Limits = [0 Inf];
            app.ConnectTimeout.RoundFractionalValues = 'on';
            app.ConnectTimeout.ValueDisplayFormat = '%.0f';
            app.ConnectTimeout.Tag = 'ConnectTimeout';
            app.ConnectTimeout.Value = 10;
            app.UseProxyDropDownLabel = uilabel(app.GridLayout2);
            app.UseProxyDropDownLabel.Text = 'Use Proxy';
            app.UseProxy = uidropdown(app.GridLayout2);
            app.UseProxy.Items = {'Yes', 'No'};
            app.UseProxy.ItemsData = [1 0];
            app.UseProxy.Tag = 'UseProxy';
            app.UseProxy.Value = 1;
            app.UseProgressMonitorDropDownLabel = uilabel(app.GridLayout2);
            app.UseProgressMonitorDropDownLabel.Tag = 'UseProgressMonitor';
            app.UseProgressMonitorDropDownLabel.Text = 'Use Progress Monitor';
            app.UseProgressMonitor = uidropdown(app.GridLayout2);
            app.UseProgressMonitor.Items = {'No', 'Yes'};
            app.UseProgressMonitor.ItemsData = [0 1];
            app.UseProgressMonitor.Value = 0;
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.ConnectionSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectionConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConnectionSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConnectionSettingsUIFigure)
        end
    end
end