classdef DataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IAMExplorerUIFigure             matlab.ui.Figure
        GridLayout11                    matlab.ui.container.GridLayout
        HTML2                           matlab.ui.control.HTML
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseExplorerTab             matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        VariableDropDownLabel           matlab.ui.control.Label
        VariableDropDown                matlab.ui.control.DropDown
        RunDropDownLabel                matlab.ui.control.Label
        RunDropDown                     matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        LoadandPreviewDataButton        matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        SelectedtoWorkspaceButton_2     matlab.ui.control.Button
        AlltoWorkspaceButton_2          matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        SearchDatabaseTab               matlab.ui.container.Tab
        WorkspaceTab                    matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        Panel_12                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        ClearWorkspaceButton            matlab.ui.control.Button
        ExportAllButton                 matlab.ui.control.Button
        ExportSelectedButton            matlab.ui.control.Button
        LoadWorkspaceButton             matlab.ui.control.Button
        PlotSelectedButton_2            matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        RegionsListBox                  matlab.ui.control.ListBox
        RegionsListBoxLabel             matlab.ui.control.Label
        VariablesListBox                matlab.ui.control.ListBox
        VariablesListBoxLabel           matlab.ui.control.Label
        ScenariosListBox                matlab.ui.control.ListBox
        ScenariosListBoxLabel           matlab.ui.control.Label
        ModelsListBoxLabel              matlab.ui.control.Label
        ModelsListBox                   matlab.ui.control.ListBox
        InformationTab                  matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        TimeseriesInfoDropDown          matlab.ui.control.DropDown
        VariableLabel                   matlab.ui.control.Label
        RegionInfoDropDown              matlab.ui.control.DropDown
        RegionDropDownLabel             matlab.ui.control.Label
        ScenarioInfoDropDown            matlab.ui.control.DropDown
        ScenarioDropDown_2Label         matlab.ui.control.Label
        ModelInfoDropDown               matlab.ui.control.DropDown
        ModelDropDown_2Label            matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        Panel_14                        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DefaultrunsonlyCheckBox         matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        SelectenvironmentDropDownLabel  matlab.ui.control.Label
        SelectenvironmentDropDown       matlab.ui.control.DropDown
    end

    
    % Copyright 2020-2021 The MathWorks, Inc.
    
    properties (Access = private)
        Environment (1,1) iam.IAMEnvironment
        Data (:,1) struct = struct('model', {}, 'scenario', {}, 'variable', {}, 'region', {}, 'runId', {}, 'version', {}, 'unit', {}, 'years', {}, 'values', {});
        Workspace (1,1) iam.IAMWorkspace
        PreviewData iam.IAMTimeseries
        
        SearchDatabase iam.controls.SearchDatabaseControls;
        
        AvailableEnvironments = ["cdlinks";"hotspots";"ngfs";"paris_lttg";"ripples";"setnav";"iamc15"; "openentrance";"limits"];
        
        ExplorerAxes
        WorkspaceAxes
    end
    
    methods (Access = private)
        
        function generateWorkspace(app, data)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Adding data to Workspace', 'Indeterminate','on');
            
            if ~isempty(data)
                app.Workspace.addData(data);
                
                % set models
                app.ModelsListBox.Items = app.Workspace.Models;
                app.ModelsListBox.Value = {};
                app.Workspace.removeFilter('Model');
                
                % set scenario
                app.ScenariosListBox.Items = app.Workspace.Scenarios;
                app.ScenariosListBox.Value = {};
                app.Workspace.removeFilter('Scenario');
                
                % set variable (no repeated values allowed)
                currentVariable = app.VariablesListBox.Value;
                app.VariablesListBox.Items = app.Workspace.Variables;
                if isempty(currentVariable)
                    currentVariable = app.VariablesListBox.Items{1};
                end
                app.VariablesListBox.Value = currentVariable;
                app.Workspace.addFilter('Variable', currentVariable)
                
                % set region
                regions = app.Workspace.Regions;
                app.RegionsListBox.Items = regions;
                
                try
                    app.RegionsListBox.Value = {'World'};
                    app.Workspace.addFilter('Region', 'World')
                catch
                    app.RegionsListBox.Value = {};
                    app.Workspace.removeFilter('region');
                end
                
            else
                app.ModelsListBox.Items = {};
                app.ScenariosListBox.Items = {};
                app.VariablesListBox.Items = {};
                app.RegionsListBox.Items = {};
                cla(app.WorkspaceAxes.UIAxes)
            end
            
            close(d)
            
        end
        
        function exportData(app, filter)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Exporting data', 'Indeterminate','on');
            if filter
                data = app.Workspace.FilteredData;
            else
                data = app.Workspace.Data;
            end
            
            if isdeployed()
                uisave('data')
            else
                var = inputdlg('Please select a name','Export to Workspace',1);
                assignin('base', var{1}, data)
            end
            
            close(d)
        end
        
        function modifyFilter(app, type, value)
            if isempty(value)
                app.Workspace.removeFilter(type)
            else
                app.Workspace.addFilter(type, value)
            end
        end
        
        function setEnv(app)
            
            app.Label.Text = app.Environment.Connection.Config.welcome;
            app.setModel();
            
            app.ModelInfoDropDown.Items = ["";app.Environment.Models.Name];
            app.ModelInfoDropDown.ItemsData = [0;app.Environment.Models.ID];
            
            app.ScenarioInfoDropDown.Items = ["";app.Environment.Scenarios.Name];
            app.ScenarioInfoDropDown.ItemsData = [0;app.Environment.Scenarios.ID];
            
            app.RegionInfoDropDown.Items = ["";app.Environment.Regions.Name];
            app.RegionInfoDropDown.ItemsData = [0;app.Environment.Regions.ID];
            
            app.TimeseriesInfoDropDown.Items = ["";app.Environment.Variables.Name];
            app.TimeseriesInfoDropDown.ItemsData = [0;app.Environment.Variables.ID];
            
            % Add search tree tabs
            app.SearchDatabase.addMetadataTree(app.Environment.Variables.Name,'Variables');
            app.SearchDatabase.addMetadataTree(app.Environment.Regions.Name,'Regions');
            app.SearchDatabase.addMetadataTree(app.Environment.Models.Name,'Models');
            app.SearchDatabase.addMetadataTree(app.Environment.Scenarios.Name,'Scenarios');
            
        end
        
        function setModel(app)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Getting Models', 'Indeterminate','on');
            
            models = app.Environment.Models.Name;
            
            if ~isempty(models)
                
                app.ModelDropDown.Items = models;
                app.setScenarios();
                
            else
                
                app.ModelDropDown.Items = {''};
                app.ScenarioDropDown.Items = {''};
                app.RunDropDown.Items     = {''};
                app.VariableDropDown.Items = {''};
                
            end
            
            close(d);
        end
        
        function setScenarios(app)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Getting Scenarios', 'Indeterminate','on');
            
            idx = app.Environment.RunList.model == app.ModelDropDown.Value;
            
            scenarios = unique(app.Environment.RunList.scenario(idx));
            
            if ~isempty(scenarios)
                
                app.ScenarioDropDown.Items = scenarios;
                app.setRuns();
                
            else
                app.ScenarioDropDown.Items = {''};
                app.RunDropDown.Items      = {''};
                app.VariableDropDown.Items = {''};
            end
            
            close(d);
            
        end
        
        function setRuns(app)
            
            model = app.ModelDropDown.Value;
            scenario = app.ScenarioDropDown.Value;
            runId = app.Environment.filterRuns(true, 'models',model,'scenarios',scenario);
            app.RunDropDown.Items = string(runId);
            app.RunDropDown.ItemsData = runId;
            
            if numel(runId) == 1
                app.RunDropDown.Enable = false;
            else
                app.RunDropDown.Enable = true;
            end
            
            app.setVariables();
            
        end
        
        function setVariables(app)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching Variables', 'Indeterminate','on');
            
            if ~isempty(app.RunDropDown.Value)
                app.VariableDropDown.Items = app.Environment.getRunVariables(app.RunDropDown.Value);
            else
                app.VariableDropDown.Items = {''};
            end
            
            close(d);
            
        end
                
        function LoadDataButtonPushed(app)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching data', 'Indeterminate','on');
            
            [data, strict] = app.SearchDatabase.getCallDetails('Variables');
            vr = app.Environment.filterVariables(data, strict);
            
            [data, strict] = app.SearchDatabase.getCallDetails('Regions');
            rg = app.Environment.filterRegions(data, strict);
            
            [data, strict] = app.SearchDatabase.getCallDetails('Models');
            runIDM = app.Environment.filterRuns('models',data,'strict', strict);
            
            [data, strict] = app.SearchDatabase.getCallDetails('Scenarios');
            runIDS = app.Environment.filterRuns('scenarios',data,'strict', strict);
            
            if isempty(vr)
                noMatch("Variables")
                close(d)
                return
            end
            
            if isempty(rg)
                noMatch("Regions")
                close(d)
                return
            end
            
            runs = intersect(runIDM,runIDS);
            
            if isempty(runs)
                noMatch("Models and Scenarios")
                return
            end
            
            ts = app.Environment.getTimeSeries('runs',runs,'variables',vr,'regions',rg,'strict',true);

            app.SearchDatabase.loadData(ts);
            
            close(d)
            
            function noMatch(variable)
                uialert(app.IAMExplorerUIFigure,"No "+ variable + " match the current value", "No match")
                close(d)
            end
        end
        
        % Button pushed function: LoadWorkspaceButton
        % Callback function
        function SelectedtoWorkspaceButtonPushed(app)
            [~,b] = intersect(app.SearchDatabase.TableData.getRunList, app.SearchDatabase.SelectedTableData);
            app.generateWorkspace(app.SearchDatabase.TableData(b));
        end

        % Callback function
        function AlltoWorkspaceButtonPushed(app)
            app.generateWorkspace(app.SearchDatabase.TableData);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Environment)
            
            % Set Database Explorer Tab axis
            DEAx = iam.views.createAxesWithLegend('Parent',app.GridLayout7);
            DEAx.GridLayout.Layout.Row = 1;
            DEAx.GridLayout.Layout.Column = 2;
            
            app.ExplorerAxes = DEAx;
            
            % Set Workspace Tab axis
            WAx = iam.views.createAxesWithLegend('Parent',app.GridLayout3);
            WAx.GridLayout.Layout.Row = 2;
            WAx.GridLayout.Layout.Column = 1;
            
            app.WorkspaceAxes = WAx;
            
            % Add Search tab.
            app.SearchDatabase = iam.controls.SearchDatabaseControls('Parent',app.SearchDatabaseTab);
            app.SearchDatabase.LoadDataButton.ButtonPushedFcn = @(s,e) app.LoadDataButtonPushed();
            app.SearchDatabase.SelectedtoWorkspaceButton.ButtonPushedFcn = @(s,e) app.SelectedtoWorkspaceButtonPushed();
            app.SearchDatabase.AlltoWorkspaceButton.ButtonPushedFcn = @(s,e) app.AlltoWorkspaceButtonPushed();
            
            % Set Up Environment.            
            app.Environment = Environment;
            
            if ~ismember(Environment.Connection.getEnvironmentConfig.env,app.AvailableEnvironments)
                str = strjoin(app.AvailableEnvironments,', ');
                delete(app)
                error("Please use the app only with the following selected enviroments: " + str)
            else
                idx = ismember(app.Environment.viewEnvironments.env,app.AvailableEnvironments);
                
                app.SelectenvironmentDropDown.Items = app.Environment.viewEnvironments.productName(idx);
                app.SelectenvironmentDropDown.Value = app.Environment.Environment;
                
                app.setEnv();
            end
        end

        % Value changed function: SelectenvironmentDropDown
        function SelectenvironmentDropDownValueChanged(app, event)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Retrieving Models', 'Indeterminate','on');
            
            app.Environment.changeEnvironment(event.Value);
            
            if ~isempty(event.Value)
                app.setEnv();
            end
            
            close(d);
            
        end

        % Button pushed function: LoadandPreviewDataButton
        function LoadandPreviewDataButtonPushed(app, event)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching data', 'Indeterminate','on');
            runId = app.RunDropDown.Value;
            var = app.VariableDropDown.Value;
            
            try
                data = app.Environment.getTimeSeries('runs', runId, 'variables', var,'strict',true);
                
                if ~isempty(data)
                    
                    app.PreviewData = data;
                    
                    % Fill listbox
                    regions = data.uniqueRegions();
                    app.ListBox.Items = regions;
                    
                    % Attempt to find World
                    idx = strcmpi(regions, 'World');
                    if any(idx)
                        app.ListBox.Value = {'World'};
                        data2 = data([data.Region] == "World");
                    else
                        rg = app.ListBox.Items{1};
                        app.ListBox.Value = rg;
                        data2 = data([data.Region] == rg);
                    end
                    
                    plot(data2,'Parent',app.ExplorerAxes.UIAxes, 'LineWidth', 2);
                    
                else
                    uialert(app.IAMExplorerUIFigure, 'The data was empty', 'No data')
                end
                
            catch
                uialert(app.IAMExplorerUIFigure, 'The server returned an error for the choosen selection', 'Invalid query', 'Icon', "error")
            end
            
            close(d);
            
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.setModel();
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            app.setScenarios();
        end

        % Value changed function: RunDropDown
        function RunDropDownValueChanged(app, event)
            app.setRuns();
        end

        % Button pushed function: PlotSelectedButton_2
        function PlotSelectedButton_2Pushed(app, event)
            
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Plotting data', 'Indeterminate','on');
            
            h = app.Workspace.plot(true, 'Parent', app.WorkspaceAxes.UIAxes, 'LineWidth', 2);
            notify(app.WorkspaceAxes,'PlotChanged')
            
            if isempty(h)
                cla(app.WorkspaceAxes.UIAxes);
            end
            
            close(d)
            
        end

        % Value changed function: RegionsListBox
        function RegionsListBoxValueChanged(app, event)
            value = app.RegionsListBox.Value;
            app.modifyFilter('Region', value)
        end

        % Value changed function: ScenariosListBox
        function ScenariosListBoxValueChanged(app, event)
            value = app.ScenariosListBox.Value;
            app.modifyFilter('Scenario', value)
        end

        % Value changed function: ModelsListBox
        function ModelsListBoxValueChanged(app, event)
            value = app.ModelsListBox.Value;
            app.modifyFilter('Model', value)
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
            value = app.VariablesListBox.Value;
            app.modifyFilter('Variable', value)
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            app.exportData(false)
        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            app.exportData(true)
        end

        % Button pushed function: SelectedtoWorkspaceButton_2
        function SelectedtoWorkspaceButton_2Pushed(app, event)
            value = app.ListBox.Value;
            idx = ismember([app.PreviewData.Region] , value);
            app.generateWorkspace(app.PreviewData(idx));
        end

        % Button pushed function: AlltoWorkspaceButton_2
        function AlltoWorkspaceButton_2Pushed(app, event)
            app.generateWorkspace(app.PreviewData);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            idx = ismember([app.PreviewData.Region] , value);
            plot(app.PreviewData(idx), ...
                'Parent',app.ExplorerAxes.UIAxes, ...
                'LineWidth', 2);
            notify(app.ExplorerAxes,'PlotChanged')
        end

        % Button pushed function: LoadWorkspaceButton
        function LoadWorkspaceButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            
            if ~isnumeric(file)
                var = load(fullfile(path, file));
                namevar = fields(var);
                try
                    app.generateWorkspace(var.(namevar{1}));
                catch
                    uialert(app.IAMExplorerUIFigure, 'Invalid data')
                end
            end
        end

        % Value changed function: DefaultrunsonlyCheckBox
        function DefaultrunsonlyCheckBoxValueChanged(app, event)
            value = app.DefaultrunsonlyCheckBox.Value;
            app.Environment.Connection.DefaultRunsOnly = value;
            app.setEnv();
        end

        % Value changed function: ModelInfoDropDown
        function ModelInfoDropDownValueChanged(app, event)
            value = app.ModelInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getModelDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ScenarioInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: ScenarioInfoDropDown
        function ScenarioInfoDropDownValueChanged(app, event)
            value = app.ScenarioInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getScenarioDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ModelInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: RegionInfoDropDown
        function RegionInfoDropDownValueChanged(app, event)
            value = app.RegionInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getRegionDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ModelInfoDropDown.Value = 0;
            app.ScenarioInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: TimeseriesInfoDropDown
        function TimeseriesInfoDropDownValueChanged(app, event)
            value = app.TimeseriesInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getTimeseriesDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = "<p>" + doc.description + "</p>";
                end
            end
            app.ModelInfoDropDown.Value = 0;
            app.ScenarioInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
        end

        % Button pushed function: ClearWorkspaceButton
        function ClearWorkspaceButtonPushed(app, event)
            app.Workspace =  iam.IAMWorkspace();
            app.generateWorkspace([]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IAMExplorerUIFigure = uifigure('Visible', 'off');
            app.IAMExplorerUIFigure.Name = 'IAM Explorer';
            app.GridLayout11 = uigridlayout(app.IAMExplorerUIFigure);
            app.Panel_14 = uipanel(app.GridLayout11);
            app.GridLayout = uigridlayout(app.Panel_14);
            app.SelectenvironmentDropDown = uidropdown(app.GridLayout);
            app.SelectenvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectenvironmentDropDownValueChanged, true);
            app.SelectenvironmentDropDownLabel = uilabel(app.GridLayout);
            app.SelectenvironmentDropDownLabel.Text = 'Select environment';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.DefaultrunsonlyCheckBox = uicheckbox(app.GridLayout);
            app.DefaultrunsonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultrunsonlyCheckBoxValueChanged, true);
            app.DefaultrunsonlyCheckBox.Enable = 'off';
            app.DefaultrunsonlyCheckBox.Text = 'Default runs only';
            app.DefaultrunsonlyCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.GridLayout11);
            app.DatabaseExplorerTab = uitab(app.TabGroup);
            app.DatabaseExplorerTab.Title = 'Database Explorer';
            app.GridLayout7 = uigridlayout(app.DatabaseExplorerTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Panel_4 = uipanel(app.GridLayout8);
            app.GridLayout10 = uigridlayout(app.Panel_4);
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.GridLayout23 = uigridlayout(app.GridLayout10);
            app.AlltoWorkspaceButton_2 = uibutton(app.GridLayout23, 'push');
            app.AlltoWorkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlltoWorkspaceButton_2Pushed, true);
            app.AlltoWorkspaceButton_2.Text = 'All to Workspace';
            app.SelectedtoWorkspaceButton_2 = uibutton(app.GridLayout23, 'push');
            app.SelectedtoWorkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectedtoWorkspaceButton_2Pushed, true);
            app.SelectedtoWorkspaceButton_2.Text = 'Selected to Workspace';
            app.Panel_5 = uipanel(app.GridLayout8);
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.LoadandPreviewDataButton = uibutton(app.GridLayout5, 'push');
            app.LoadandPreviewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandPreviewDataButtonPushed, true);
            app.LoadandPreviewDataButton.Text = 'Load and Preview Data';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ModelDropDown = uidropdown(app.GridLayout6);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = {};
            app.ModelDropDownLabel = uilabel(app.GridLayout6);
            app.ModelDropDownLabel.Text = 'Model';
            app.ScenarioDropDown = uidropdown(app.GridLayout6);
            app.ScenarioDropDown.Items = {};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Value = {};
            app.ScenarioDropDownLabel = uilabel(app.GridLayout6);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.RunDropDown = uidropdown(app.GridLayout6);
            app.RunDropDown.Items = {};
            app.RunDropDown.ValueChangedFcn = createCallbackFcn(app, @RunDropDownValueChanged, true);
            app.RunDropDown.Value = {};
            app.RunDropDownLabel = uilabel(app.GridLayout6);
            app.RunDropDownLabel.Text = 'Run';
            app.VariableDropDown = uidropdown(app.GridLayout6);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.Editable = 'on';
            app.VariableDropDown.Value = {};
            app.VariableDropDownLabel = uilabel(app.GridLayout6);
            app.VariableDropDownLabel.Text = 'Variable';
            app.SearchDatabaseTab = uitab(app.TabGroup);
            app.SearchDatabaseTab.Title = 'Search Database';
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            app.GridLayout21 = uigridlayout(app.WorkspaceTab);
            app.Panel_13 = uipanel(app.GridLayout21);
            app.GridLayout2 = uigridlayout(app.Panel_13);
            app.ModelsListBox = uilistbox(app.GridLayout2);
            app.ModelsListBox.Items = {};
            app.ModelsListBox.Multiselect = 'on';
            app.ModelsListBox.ValueChangedFcn = createCallbackFcn(app, @ModelsListBoxValueChanged, true);
            app.ModelsListBox.Value = {};
            app.ModelsListBoxLabel = uilabel(app.GridLayout2);
            app.ModelsListBoxLabel.Text = 'Models';
            app.ScenariosListBoxLabel = uilabel(app.GridLayout2);
            app.ScenariosListBoxLabel.Text = 'Scenarios';
            app.ScenariosListBox = uilistbox(app.GridLayout2);
            app.ScenariosListBox.Items = {};
            app.ScenariosListBox.Multiselect = 'on';
            app.ScenariosListBox.ValueChangedFcn = createCallbackFcn(app, @ScenariosListBoxValueChanged, true);
            app.ScenariosListBox.Value = {};
            app.VariablesListBoxLabel = uilabel(app.GridLayout2);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.GridLayout2);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.Multiselect = 'on';
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = {};
            app.RegionsListBoxLabel = uilabel(app.GridLayout2);
            app.RegionsListBoxLabel.Text = 'Regions';
            app.RegionsListBox = uilistbox(app.GridLayout2);
            app.RegionsListBox.Items = {};
            app.RegionsListBox.Multiselect = 'on';
            app.RegionsListBox.ValueChangedFcn = createCallbackFcn(app, @RegionsListBoxValueChanged, true);
            app.RegionsListBox.Value = {};
            app.Panel_12 = uipanel(app.GridLayout21);
            app.GridLayout3 = uigridlayout(app.Panel_12);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PlotSelectedButton_2 = uibutton(app.GridLayout4, 'push');
            app.PlotSelectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedButton_2Pushed, true);
            app.PlotSelectedButton_2.Text = 'Plot Selected';
            app.LoadWorkspaceButton = uibutton(app.GridLayout4, 'push');
            app.LoadWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkspaceButtonPushed, true);
            app.LoadWorkspaceButton.Text = 'Load Workspace';
            app.ExportSelectedButton = uibutton(app.GridLayout4, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Text = 'Export Selected';
            app.ExportAllButton = uibutton(app.GridLayout4, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.ClearWorkspaceButton = uibutton(app.GridLayout4, 'push');
            app.ClearWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWorkspaceButtonPushed, true);
            app.ClearWorkspaceButton.Text = 'Clear Workspace';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.GridLayout26 = uigridlayout(app.InformationTab);
            app.HTML = uihtml(app.GridLayout26);
            app.ModelDropDown_2Label = uilabel(app.GridLayout26);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelInfoDropDown = uidropdown(app.GridLayout26);
            app.ModelInfoDropDown.Editable = 'on';
            app.ModelInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelInfoDropDownValueChanged, true);
            app.ScenarioDropDown_2Label = uilabel(app.GridLayout26);
            app.ScenarioDropDown_2Label.Text = 'Scenario';
            app.ScenarioInfoDropDown = uidropdown(app.GridLayout26);
            app.ScenarioInfoDropDown.Editable = 'on';
            app.ScenarioInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioInfoDropDownValueChanged, true);
            app.RegionDropDownLabel = uilabel(app.GridLayout26);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionInfoDropDown = uidropdown(app.GridLayout26);
            app.RegionInfoDropDown.Editable = 'on';
            app.RegionInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionInfoDropDownValueChanged, true);
            app.VariableLabel = uilabel(app.GridLayout26);
            app.VariableLabel.Text = 'Variable';
            app.TimeseriesInfoDropDown = uidropdown(app.GridLayout26);
            app.TimeseriesInfoDropDown.Editable = 'on';
            app.TimeseriesInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeseriesInfoDropDownValueChanged, true);
            app.HTML2 = uihtml(app.GridLayout11);
            app.HTML2.HTMLSource = '<p style="padding-left:10px;padding-top:7px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:11px;">     Your use of the data sets provided by IIASA may be subject to     <a  href="https://data.ene.iiasa.ac.at/auth" target="_blank">        separate license terms    </a> and the     <a href="https://iiasa.ac.at/web/terms-of-use.html" target="_blank">        IIASA Terms of Use    </a>.     Please review these terms carefully before using these data sets</p>';
            % Show the figure after all components are created
            app.IAMExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataExplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IAMExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IAMExplorerUIFigure)
        end
    end
end