classdef DataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IAMExplorerUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ConnectionSettingsMenu          matlab.ui.container.Menu
        GridLayout11                    matlab.ui.container.GridLayout
        HTML2                           matlab.ui.control.HTML
        Panel_15                        matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DATABASEEXPLORER                matlab.ui.container.Tab
        FILTER                          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        VariableDropDownLabel           matlab.ui.control.Label
        VariableDropDown                matlab.ui.control.DropDown
        RunDropDownLabel                matlab.ui.control.Label
        RunDropDown                     matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        LoadandPreviewDataButton        matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        SelectedtoWorkbookButton        matlab.ui.control.Button
        AlltoWorkbookButton             matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        WORKBOOKTab                     matlab.ui.container.Tab
        INFORMATIONTab                  matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        TimeseriesInfoDropDown          matlab.ui.control.DropDown
        VariableLabel                   matlab.ui.control.Label
        RegionInfoDropDown              matlab.ui.control.DropDown
        RegionDropDownLabel             matlab.ui.control.Label
        ScenarioInfoDropDown            matlab.ui.control.DropDown
        ScenarioDropDown_2Label         matlab.ui.control.Label
        ModelInfoDropDown               matlab.ui.control.DropDown
        ModelDropDown_2Label            matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        Panel_14                        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        DefaultrunsonlyCheckBox         matlab.ui.control.CheckBox
        SelectenvironmentDropDownLabel  matlab.ui.control.Label
        SelectenvironmentDropDown       matlab.ui.control.DropDown
    end


    % Copyright 2020-2021 The MathWorks, Inc.

    properties (Access = private)
        Environment (1,1) iam.IAMEnvironment
        Data (:,1) struct = struct('model', {}, 'scenario', {}, 'variable', {}, 'region', {}, 'runId', {}, 'version', {}, 'unit', {}, 'years', {}, 'values', {});
        PreviewData iam.IAMTimeseries

        SearchDatabase iam.controls.SearchDatabaseControls;

        AvailableEnvironments (1,:) string

        ExplorerAxes iam.views.IAMChart

        Workbook iam.controls.IAMworkbook

        SettingsApp
    end

    methods (Access = private)

        function exportData(app, filter)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Exporting data', 'Indeterminate','on');
            if filter
                data = app.Workspace.FilteredData;
            else
                data = app.Workspace.Data;
            end

            if isdeployed()
                uisave('data')
            else
                var = inputdlg('Please select a name','Export to Workspace',1);
                assignin('base', var{1}, data)
            end

            close(d)
        end

        function modifyFilter(app, type, value)
            if isempty(value)
                app.Workspace.removeFilter(type)
            else
                app.Workspace.addFilter(type, value)
            end
        end

        function setEnv(app)

            % For IIASA, there are two options for runs. Default and
            % non-default.
            if isa(app.Environment.Connection, 'iam.data.IIASAconnection')
                app.DefaultrunsonlyCheckBox.Enable = true;
            end

            app.Label.Text = app.Environment.Connection.Config.welcome;
            app.Label.Tooltip = app.Environment.Connection.Config.welcome;
            app.setModel();

            app.ModelInfoDropDown.Items = ["";app.Environment.Models.Name];
            app.ModelInfoDropDown.ItemsData = [0;app.Environment.Models.ID];

            app.ScenarioInfoDropDown.Items = ["";app.Environment.Scenarios.Name];
            app.ScenarioInfoDropDown.ItemsData = [0;app.Environment.Scenarios.ID];

            app.RegionInfoDropDown.Items = ["";app.Environment.Regions.Name];
            app.RegionInfoDropDown.ItemsData = [0;app.Environment.Regions.ID];

            app.TimeseriesInfoDropDown.Items = ["";unique(app.Environment.Variables.Name)];
            % app.TimeseriesInfoDropDown.ItemsData = [0;app.Environment.Variables.ID];

            % Add search tree tabs
            app.SearchDatabase.addMetadataTree(app.Environment.Variables.Name,'Variables');
            app.SearchDatabase.addMetadataTree(app.Environment.Regions.Name,'Regions');
            app.SearchDatabase.addMetadataTree(app.Environment.Models.Name,'Models');
            app.SearchDatabase.addMetadataTree(app.Environment.Scenarios.Name,'Scenarios');

        end

        function setModel(app)

            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Getting Models', 'Indeterminate','on');

            models = app.Environment.Models.Name;

            if ~isempty(models)

                app.ModelDropDown.Items = models;
                app.setScenarios();

            else

                app.ModelDropDown.Items = {''};
                app.ScenarioDropDown.Items = {''};
                app.RunDropDown.Items     = {''};
                app.VariableDropDown.Items = {''};

            end

            close(d);
        end

        function setScenarios(app)

            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Getting Scenarios', 'Indeterminate','on');

            idx = app.Environment.RunList.model == app.ModelDropDown.Value;

            scenarios = unique(app.Environment.RunList.scenario(idx));

            if ~isempty(scenarios)

                app.ScenarioDropDown.Items = scenarios;
                app.setRuns();

            else
                app.ScenarioDropDown.Items = {''};
                app.RunDropDown.Items      = {''};
                app.VariableDropDown.Items = {''};
            end

            close(d);

        end

        function setRuns(app)

            model = app.ModelDropDown.Value;
            scenario = app.ScenarioDropDown.Value;
            runId = app.Environment.filterRuns(true, 'models',model,'scenarios',scenario);
            app.RunDropDown.Items = string(runId);
            app.RunDropDown.ItemsData = runId;

            if isscalar(runId)
                app.RunDropDown.Enable = false;
            else
                app.RunDropDown.Enable = true;
            end

            app.setVariables();

        end

        function setVariables(app)

            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching Variables', 'Indeterminate','on');

            if ~isempty(app.RunDropDown.Value)
                app.VariableDropDown.Items = app.Environment.getRunVariables(app.RunDropDown.Value);
                app.VariableDropDown.Value = app.VariableDropDown.Items(1);
            else
                app.VariableDropDown.Items = {''};
            end

            close(d);

        end

        function LoadDataButtonPushed(app)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching data', 'Indeterminate','on');

            [data, strict] = app.SearchDatabase.getCallDetails('Variables');
            vr = app.Environment.filterVariables(data, strict);

            [data, strict] = app.SearchDatabase.getCallDetails('Regions');
            rg = app.Environment.filterRegions(data, strict);

            [data, strict] = app.SearchDatabase.getCallDetails('Models');
            runIDM = app.Environment.filterRuns('models',data,'strict', strict);

            [data, strict] = app.SearchDatabase.getCallDetails('Scenarios');
            runIDS = app.Environment.filterRuns('scenarios',data,'strict', strict);

            if isempty(vr)
                noMatch("Variables")
                close(d)
                return
            end

            if isempty(rg)
                noMatch("Regions")
                close(d)
                return
            end

            runs = intersect(runIDM,runIDS);

            if isempty(runs)
                noMatch("Models and Scenarios")
                close(d)
                return
            end

            ts = app.Environment.getTimeSeries('runs',runs,'variables',vr,'regions',rg,'strict',true);

            app.SearchDatabase.loadData(ts);

            close(d)

            function noMatch(variable)
                uialert(app.IAMExplorerUIFigure,"No "+ variable + " match the current value", "No match")
            end
        end

        % Button pushed function: LoadWorkspaceButton
        % Callback function
        function SelectedtoWorkspaceButtonPushed(app)
            app.loadWorkbook;
            [~,b] = intersect(app.SearchDatabase.TableData.getRunList, app.SearchDatabase.SelectedTableData);
            app.Workbook.addData(app.SearchDatabase.TableData(b));
        end

        % Callback function
        function AlltoWorkspaceButtonPushed(app)
            app.loadWorkbook;
            app.Workbook.addData(app.SearchDatabase.TableData);
        end
        
        function finalizeSetup(app, Environment, AvailableEnviornments)

            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Setting up environment', 'Indeterminate','on');
            % Set Up Environment.
            if nargin == 1
                c = iam.data.IIASAconnection('ngfs_phase_4');
                Environment = iam.IAMEnvironment(c);
            end

            if nargin == 3
                app.AvailableEnvironments = AvailableEnviornments;
            else
                app.AvailableEnvironments = Environment.viewEnvironments.env;
            end

            app.Environment = Environment;

            idx = ismember(app.Environment.viewEnvironments.env,app.AvailableEnvironments);

            app.SelectenvironmentDropDown.Items = app.Environment.viewEnvironments.productName(idx);
            app.SelectenvironmentDropDown.Value = app.Environment.Environment;

            app.setEnv();
            delete(d)
            
        end
        
        function loadWorkbook(app)
            if isempty(app.Workbook)
                d = uiprogressdlg(app.IAMExplorerUIFigure, 'Indeterminate','on', 'Message', 'Loading Workbook ...', 'Title', 'Loading');
                % Set workbook tab
                app.Workbook = iam.controls.IAMworkbook('Parent', app.WORKBOOKTab);
                delete(d)
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Environment, AvailableEnviornments)

            % Set Database Explorer Tab axis
            DEAx = iam.views.IAMChart('Parent',app.GridLayout7);
            DEAx.GridLayout.Layout.Row = 1;
            DEAx.GridLayout.Layout.Column = 2;
            DEAx.GridLayout.BackgroundColor = [0.23,0.29,0.22];
            DEAx.UIAxes.XColor = 'w';
            DEAx.UIAxes.YColor = 'w';
            DEAx.UIAxes.GridColor = 'k';
            DEAx.CheckBox.FontColor = 'w';
            DEAx.Label.FontColor = 'w';
            app.ExplorerAxes = DEAx;

            % Add Search tab.
            app.SearchDatabase = iam.controls.SearchDatabaseControls('Parent',app.DATABASEEXPLORER);
            app.SearchDatabase.LoadDataButton.ButtonPushedFcn = @(s,e) app.LoadDataButtonPushed();
            app.SearchDatabase.SelectedtoWorkspaceButton.ButtonPushedFcn = @(s,e) app.SelectedtoWorkspaceButtonPushed();
            app.SearchDatabase.AlltoWorkspaceButton.ButtonPushedFcn = @(s,e) app.AlltoWorkspaceButtonPushed();

            % Set Up Environment.
            drawnow();            
            if nargin < 2 || isempty(Environment)
                fcn = @(~,~) app.finalizeSetup();
            elseif nargin == 2
                fcn = @(~,~) app.finalizeSetup(Environment);
            elseif nargin == 3
                fcn = @(~,~) app.finalizeSetup(Environment, AvailableEnviornments);
            end
            
            t = timer("ExecutionMode","singleShot","StartDelay",0,"TimerFcn",fcn);
            t.StopFcn = @(s,~) delete(s);
            start(t)
        end

        % Value changed function: SelectenvironmentDropDown
        function SelectenvironmentDropDownValueChanged(app, event)
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Retrieving Models', 'Indeterminate','on');

            try
                app.Environment.changeEnvironment(event.Value);

                if ~isempty(event.Value)
                    app.setEnv();
                end

            catch

                uialert(app.IAMExplorerUIFigure, 'Unable to fully import the environment, not changing', 'Environment import failed')
                app.Environment.changeEnvironment(event.PreviousValue);
                app.SelectenvironmentDropDown.Value = event.PreviousValue;

            end

            close(d);

        end

        % Button pushed function: LoadandPreviewDataButton
        function LoadandPreviewDataButtonPushed(app, event)

            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Fetching data', 'Indeterminate','on');
            runId = app.RunDropDown.Value;
            var = app.VariableDropDown.Value;

            try
                data = app.Environment.getTimeSeries('runs', runId, 'variables', var,'strict',true);

                if ~isempty(data)

                    app.PreviewData = data;

                    % Fill listbox
                    regions = data.uniqueRegions();
                    app.ListBox.Items = regions;

                    % Attempt to find World
                    idx = strcmpi(regions, 'World');
                    if any(idx)
                        app.ListBox.Value = {'World'};
                        data2 = data([data.Region] == "World");
                    else
                        rg = app.ListBox.Items{1};
                        app.ListBox.Value = rg;
                        data2 = data([data.Region] == rg);
                    end
                    app.ExplorerAxes.changeData(data2)

                else
                    uialert(app.IAMExplorerUIFigure, 'The data was empty', 'No data')
                end

            catch
                uialert(app.IAMExplorerUIFigure, 'The server returned an error for the choosen selection', 'Invalid query', 'Icon', "error")
            end

            close(d);

        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.setModel();
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            app.setScenarios();
        end

        % Value changed function: RunDropDown
        function RunDropDownValueChanged(app, event)
            app.setRuns();
        end

        % Button pushed function: SelectedtoWorkbookButton
        function SelectedtoWorkbookButtonPushed(app, event)
            value = app.ListBox.Value;
            if ~isempty(value)
                idx = ismember([app.PreviewData.Region] , value);
                app.Workbook.addData(app.PreviewData(idx));
            else
                uialert(app.IAMExplorerUIFigure, 'No data to send, plase load some data first', 'No Data')
            end
        end

        % Button pushed function: AlltoWorkbookButton
        function AlltoWorkbookButtonPushed(app, event)
            app.Workbook.addData(app.PreviewData);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            idx = ismember([app.PreviewData.Region] , value);
            app.ExplorerAxes.changeData(app.PreviewData(idx))
            notify(app.ExplorerAxes,'PlotChanged')
        end

        % Value changed function: DefaultrunsonlyCheckBox
        function DefaultrunsonlyCheckBoxValueChanged(app, event)
            value = app.DefaultrunsonlyCheckBox.Value;
            app.Environment.Connection.DefaultRunsOnly = value;
            d = uiprogressdlg(app.IAMExplorerUIFigure,'Title','Reloading Enviornment', 'Indeterminate','on');
            cleaobj = onCleanup(@() delete(d));
            app.Environment.reloadEnvironment();
            app.setEnv();
        end

        % Value changed function: ModelInfoDropDown
        function ModelInfoDropDownValueChanged(app, event)
            value = app.ModelInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getModelDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ScenarioInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: ScenarioInfoDropDown
        function ScenarioInfoDropDownValueChanged(app, event)
            value = app.ScenarioInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getScenarioDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ModelInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: RegionInfoDropDown
        function RegionInfoDropDownValueChanged(app, event)
            value = app.RegionInfoDropDown.Value;
            if value ~= 0
                doc = app.Environment.getRegionDocumentation(value);
                if isempty(doc)
                    app.HTML.HTMLSource = '<p>NO INFO</p>';
                else
                    app.HTML.HTMLSource = doc.description;
                end
            end
            app.ModelInfoDropDown.Value = 0;
            app.ScenarioInfoDropDown.Value = 0;
            app.TimeseriesInfoDropDown.Value = 0;
        end

        % Value changed function: TimeseriesInfoDropDown
        function TimeseriesInfoDropDownValueChanged(app, event)
            value = app.TimeseriesInfoDropDown.Value;
            % Get Possible ID's, the variable exists in different units

            idx = app.Environment.Variables.Name == value;
            toSearch = app.Environment.Variables(idx,:);

            for i = 1 : height(toSearch)
                doc = app.Environment.getTimeseriesDocumentation(toSearch{i,"ID"});
            end

            % if value ~= 0
            %     doc = app.Environment.getTimeseriesDocumentation(value);
            %     if isempty(doc)
            %         app.HTML.HTMLSource = '<p>NO INFO</p>';
            %     else
            %         app.HTML.HTMLSource = "<p>" + doc.description + "</p>";
            %     end
            % end
            app.ModelInfoDropDown.Value = 0;
            app.ScenarioInfoDropDown.Value = 0;
            app.RegionInfoDropDown.Value = 0;
        end

        % Menu selected function: ConnectionSettingsMenu
        function ConnectionSettingsMenuSelected(app, event)
            if isa(app.Environment.Connection, 'iam.data.IIASAconnection')
                app.ConnectionSettingsMenu.Enable = 'off';
                app.SettingsApp = iam.views.ConnectionConfig(app, app.Environment.Connection);
            else
                uialert(app.IAMExplorerUIFigure, 'No connection settings on CSV files', 'No settings')
            end
        end

        % Close request function: IAMExplorerUIFigure
        function IAMExplorerUIFigureCloseRequest(app, event)
            delete(app.SettingsApp)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            title = selectedTab.Title;

            switch title
                case "WORKBOOK"
                    app.loadWorkbook                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IAMExplorerUIFigure = uifigure('Visible', 'off');
            app.IAMExplorerUIFigure.Name = 'IAM Explorer';
            app.IAMExplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @IAMExplorerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.IAMExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.ConnectionSettingsMenu = uimenu(app.FileMenu);
            app.ConnectionSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectionSettingsMenuSelected, true);
            app.ConnectionSettingsMenu.Text = 'Connection Settings';
            app.GridLayout11 = uigridlayout(app.IAMExplorerUIFigure);
            app.Panel_14 = uipanel(app.GridLayout11);
            app.GridLayout = uigridlayout(app.Panel_14);
            app.SelectenvironmentDropDown = uidropdown(app.GridLayout);
            app.SelectenvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectenvironmentDropDownValueChanged, true);
            app.SelectenvironmentDropDownLabel = uilabel(app.GridLayout);
            app.SelectenvironmentDropDownLabel.Text = 'Select environment';
            app.DefaultrunsonlyCheckBox = uicheckbox(app.GridLayout);
            app.DefaultrunsonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultrunsonlyCheckBoxValueChanged, true);
            app.DefaultrunsonlyCheckBox.Enable = 'off';
            app.DefaultrunsonlyCheckBox.Text = 'Default runs only';
            app.DefaultrunsonlyCheckBox.Value = true;
            app.Label = uilabel(app.GridLayout);
            app.Label.Interpreter = 'html';
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout11);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DATABASEEXPLORER = uitab(app.TabGroup);
            app.DATABASEEXPLORER.Title = 'SEARCH';
            app.FILTER = uitab(app.TabGroup);
            app.FILTER.Title = 'FILTER';
            app.GridLayout7 = uigridlayout(app.FILTER);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Panel_4 = uipanel(app.GridLayout8);
            app.GridLayout10 = uigridlayout(app.Panel_4);
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.GridLayout23 = uigridlayout(app.GridLayout10);
            app.AlltoWorkbookButton = uibutton(app.GridLayout23, 'push');
            app.AlltoWorkbookButton.ButtonPushedFcn = createCallbackFcn(app, @AlltoWorkbookButtonPushed, true);
            app.AlltoWorkbookButton.Text = 'All to Workbook';
            app.SelectedtoWorkbookButton = uibutton(app.GridLayout23, 'push');
            app.SelectedtoWorkbookButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedtoWorkbookButtonPushed, true);
            app.SelectedtoWorkbookButton.Text = 'Selected to Workbook';
            app.Panel_5 = uipanel(app.GridLayout8);
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.LoadandPreviewDataButton = uibutton(app.GridLayout5, 'push');
            app.LoadandPreviewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandPreviewDataButtonPushed, true);
            app.LoadandPreviewDataButton.Text = 'Load and Preview Data';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ModelDropDown = uidropdown(app.GridLayout6);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = {};
            app.ModelDropDownLabel = uilabel(app.GridLayout6);
            app.ModelDropDownLabel.Text = 'Model';
            app.ScenarioDropDown = uidropdown(app.GridLayout6);
            app.ScenarioDropDown.Items = {};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Value = {};
            app.ScenarioDropDownLabel = uilabel(app.GridLayout6);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.RunDropDown = uidropdown(app.GridLayout6);
            app.RunDropDown.Items = {};
            app.RunDropDown.ValueChangedFcn = createCallbackFcn(app, @RunDropDownValueChanged, true);
            app.RunDropDown.Value = {};
            app.RunDropDownLabel = uilabel(app.GridLayout6);
            app.RunDropDownLabel.Text = 'Run';
            app.VariableDropDown = uidropdown(app.GridLayout6);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.Editable = 'on';
            app.VariableDropDown.Value = {};
            app.VariableDropDownLabel = uilabel(app.GridLayout6);
            app.VariableDropDownLabel.Text = 'Variable';
            app.WORKBOOKTab = uitab(app.TabGroup);
            app.WORKBOOKTab.Title = 'WORKBOOK';
            app.INFORMATIONTab = uitab(app.TabGroup);
            app.INFORMATIONTab.Title = 'INFORMATION';
            app.GridLayout26 = uigridlayout(app.INFORMATIONTab);
            app.HTML = uihtml(app.GridLayout26);
            app.HTML.HTMLSource = '<p></p>';
            app.ModelDropDown_2Label = uilabel(app.GridLayout26);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelInfoDropDown = uidropdown(app.GridLayout26);
            app.ModelInfoDropDown.Editable = 'on';
            app.ModelInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelInfoDropDownValueChanged, true);
            app.ScenarioDropDown_2Label = uilabel(app.GridLayout26);
            app.ScenarioDropDown_2Label.Text = 'Scenario';
            app.ScenarioInfoDropDown = uidropdown(app.GridLayout26);
            app.ScenarioInfoDropDown.Editable = 'on';
            app.ScenarioInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioInfoDropDownValueChanged, true);
            app.RegionDropDownLabel = uilabel(app.GridLayout26);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionInfoDropDown = uidropdown(app.GridLayout26);
            app.RegionInfoDropDown.Editable = 'on';
            app.RegionInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionInfoDropDownValueChanged, true);
            app.VariableLabel = uilabel(app.GridLayout26);
            app.VariableLabel.Text = 'Variable';
            app.TimeseriesInfoDropDown = uidropdown(app.GridLayout26);
            app.TimeseriesInfoDropDown.Editable = 'on';
            app.TimeseriesInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeseriesInfoDropDownValueChanged, true);
            app.Panel_15 = uipanel(app.GridLayout11);
            app.HTML2 = uihtml(app.GridLayout11);
            app.HTML2.HTMLSource = '<p style="padding-left:10px;padding-top:5px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:11px;">     Your use of the data sets provided by IIASA may be subject to     <a  href="https://data.ene.iiasa.ac.at/auth" target="_blank">        separate license terms    </a> and the     <a href="https://iiasa.ac.at/web/terms-of-use.html" target="_blank">        IIASA Terms of Use    </a>.     Please review these terms carefully before using these data sets</p>';
            % Show the figure after all components are created
            app.IAMExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataExplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IAMExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IAMExplorerUIFigure)
        end
    end
end