classdef IAMDataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginPage                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IIASATab                        matlab.ui.container.Tab
        MainAppGridLayout               matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GuestLoginButton                matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        NewuserButton                   matlab.ui.control.Button
        ResetpasswordButton             matlab.ui.control.Button
        HTML                            matlab.ui.control.HTML
        GridLayout3                     matlab.ui.container.GridLayout
        DefaultDatabaseDropDown         matlab.ui.control.DropDown
        DefaultDatabaseDropDownLabel    matlab.ui.control.Label
        HTMLpassword                    matlab.ui.control.HTML
        PasswordLabel                   matlab.ui.control.Label
        HTMLusername                    matlab.ui.control.HTML
        UsernameLabel                   matlab.ui.control.Label
        WelcometotheIIASAExplorerLabel  matlab.ui.control.Label
        LIMITSTab                       matlab.ui.container.Tab
        WelcometotheLIMITSExplorerLabel  matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        HTML2                           matlab.ui.control.HTML
        GridLayout11                    matlab.ui.container.GridLayout
        LoginButton_2                   matlab.ui.control.Button
        SelectExcelFileButton           matlab.ui.control.Button
        LimitsDB                        matlab.ui.control.EditField
        HTML_2                          matlab.ui.control.HTML
        BOCTab                          matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        LoginButton_3                   matlab.ui.control.Button
        WelcometotheBankofCanadaScenarioExplorerLabel  matlab.ui.control.Label
        OECMTab                         matlab.ui.container.Tab
        GridLayout12_2                  matlab.ui.container.GridLayout
        LoginButton_4                   matlab.ui.control.Button
        WelcometotheOneEarthClimateModelExplorerLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        AvailableEnvironmentsName = [...
            "AR6 Scenario Explorer  and Database hosted by IIASA";
            "Ariadne Scenario Explorer";
            "CD-LINKS Scenario Explorer hosted by IIASA";
            "Climate Solutions Scenario Explorer and Database hosted by IIASA";
            "Chinese Model Intercomparison";
            "COMMIT Scenario Explorer"
            "DEEDS Scenario Explorer hosted by IIASA";
            "ENGAGE Scenario Explorer";
            "GEI Scenario Explorer  and Database hosted by IIASA";
            "Hotspots Scenario Explorer";
            "IAMC 1.5°C Scenario Explorer hosted by IIASA";
            "Netzero2040 Scenario Explorer";
            "ISWEL Nexus Basins Scenario Explorer";
            "NGFS Phase 2 Scenario Explorer";
            "NGFS Phase 3 Scenario Explorer";
            "openENTRANCE Scenario Explorer";
            "Paris Long-Term Temperature Goal (LTTG) Scenario Explorer";
            "COP21 RIPPLES Scenario Explorer";
            "SENSES Scenario Explorer";
            "SET-Nav Scenario Explorer"];
        AvailableEnvironments = ["ar60x2Dpublic";"ariadne";"cdlinks";"climate0x2Dsolutions";"cmin";"commit";"deeds";"engage";"gei";"hotspots";"iamc15";"netzero2040";"nexus0x2Dbasins";"ngfs";"ngfs_phase_3";"openentrance";"paris_lttg";"ripples";"senses";"setnav";"limits"]; % Description
    end

    methods (Access = private)

        function login(app, c, e)

            try
                envs = c.getEnvironments.env;
            catch
                envs = app.AvailableEnvironments;
            end
            iam.DataExplorer(e, envs);
            delete(app)

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                token = webread('https://api.manager.ece.iiasa.ac.at/legacy/anonym');
                d = webread('https://api.manager.ece.iiasa.ac.at/legacy/applications', weboptions('HeaderFields', {'Authorization',['Bearer ', token]}));

                names = strings(numel(d), 1);
                envs = strings(numel(d), 1);                
                for i = 1 : numel(d)
                    envs(i) = d(i).config(1).value;
                    if numel(d(i).config) > 1
                        names(i) = d(i).config(3).value;
                    else
                        names(i) = missing;
                        envs(i) = missing;
                    end
                end
                names = names(~ismissing(names));
                envs = envs(~ismissing(envs));

                [envs, ic] = sort(envs);
                names = names(ic);
                app.AvailableEnvironmentsName = names;
                app.AvailableEnvironments = envs;
            catch e
                uialert(app.LoginPage, 'Unable to gather all IIASA envs, more might be available', 'Default Environments only', 'Icon','warning')
                pause(2)
            end

            app.DefaultDatabaseDropDown.Items = app.AvailableEnvironmentsName;
            app.DefaultDatabaseDropDown.ItemsData = app.AvailableEnvironments;
            app.DefaultDatabaseDropDown.Value = 'ngfs_phase_4';
        end

        % Button pushed function: LoginButton
        function loginUser(app, event)
            d = uiprogressdlg(app.LoginPage,'Title','Connecting to database ...', 'Indeterminate','on');
            try
                if app.DefaultDatabaseDropDown.Value ~= "boc"
                    c = iam.data.IIASAconnection(app.DefaultDatabaseDropDown.Value, app.HTMLusername.Data, app.HTMLpassword.Data );
                else
                    c = iam.data.BANKOFCANADAconnection;
                end
                % if login details are correct Authorize = true
                if c.Authorize
                    e = iam.IAMEnvironment(c);
                    close(d)
                    app.login(c, e);
                else
                    uialert(app.LoginPage, "Unauthorized", "Unauthorized")
                    close(d)
                end
            catch ME
                uialert(app.LoginPage, ME.message, "Something went wrong...")
                close(d)
            end

        end

        % Button pushed function: GuestLoginButton
        function loginGuest(app, event)
            d = uiprogressdlg(app.LoginPage,'Title','Connecting to database ...', 'Indeterminate','on');
            try
                c = iam.data.IIASAconnection(app.DefaultDatabaseDropDown.Value);
                e = iam.IAMEnvironment(c);
                app.login(c, e);
            catch
                uialert(app.LoginPage, 'Something went wrong, try with a different database. If the error persists, please make sure that you are running the latest version of the app.', 'Unable to import environment')
            end
            close(d)
        end

        % Button pushed function: NewuserButton
        function registerUser(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/register?application=IXSE_SR15', '-browser') ;
        end

        % Button pushed function: ResetpasswordButton
        function resetPassword(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/reset?application=IXSE_SR15','-browser') ;
        end

        % Button pushed function: SelectExcelFileButton
        function SelectExcelFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv';'*.xls';'*.xlsx';'*.*'}, 'Please select file containing the LIMITS database');
            app.LimitsDB.Value = fullfile(path, file);
        end

        % Button pushed function: LoginButton_2
        function LoginButton_2Pushed(app, event)
            c = iam.data.LIMITSconnection(app.LimitsDB.Value);
            e = iam.IAMEnvironment(c);
            app.login(c, e);
        end

        % Button pushed function: LoginButton_3
        function LoginButton_3Pushed(app, event)
            c = iam.data.BANKOFCANADAconnection;
            e = iam.IAMEnvironment(c);
            app.login(c, e);
        end

        % Button pushed function: LoginButton_4
        function LoginButton_4Pushed(app, event)
            c = iam.data.OECMconnection;
            e = iam.IAMEnvironment(c);
            app.login(c, e);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginPage = uifigure('Visible', 'off');
            app.LoginPage.Name = 'IAM Login Page';
            app.TabGroup = uitabgroup(app.LoginPage);
            app.IIASATab = uitab(app.TabGroup);
            app.IIASATab.Title = 'IIASA';
            app.MainAppGridLayout = uigridlayout(app.IIASATab);
            app.WelcometotheIIASAExplorerLabel = uilabel(app.MainAppGridLayout);
            app.WelcometotheIIASAExplorerLabel.Text = 'Welcome to the IIASA Explorer';
            app.GridLayout3 = uigridlayout(app.MainAppGridLayout);
            app.UsernameLabel = uilabel(app.GridLayout3);
            app.UsernameLabel.Text = '  Username';
            app.HTMLusername = uihtml(app.GridLayout3);
            app.HTMLusername.HTMLSource = '<html><head><script type="text/javascript">function setup(htmlComponent) { var dom = document.getElementById("unme");dom.addEventListener("change", function(event) {htmlComponent.Data = dom.value; });}</script></head><body><input type="text" id="unme" style="width: 96%;"></input></body></html>';
            app.PasswordLabel = uilabel(app.GridLayout3);
            app.PasswordLabel.Text = '  Password';
            app.HTMLpassword = uihtml(app.GridLayout3);
            app.HTMLpassword.HTMLSource = '<html><head><script type="text/javascript">function setup(htmlComponent) {var dom = document.getElementById("pwrd");dom.addEventListener("change", function(event) {htmlComponent.Data = dom.value; });}</script></head><body><input type="password" id="pwrd" style="width: 96%;"></input></body></html>';
            app.DefaultDatabaseDropDownLabel = uilabel(app.GridLayout3);
            app.DefaultDatabaseDropDownLabel.Text = 'Default Database';
            app.DefaultDatabaseDropDown = uidropdown(app.GridLayout3);
            app.DefaultDatabaseDropDown.Items = {'cdlinks', 'hotspots', 'ngfs_phase_3', 'paris_lttg', 'ripples', 'iamc15', 'openentrance'};
            app.DefaultDatabaseDropDown.Value = 'ngfs_phase_3';
            app.HTML = uihtml(app.MainAppGridLayout);
            app.HTML.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">    Your use of the data sets provided by IIASA may be subject to     <a  href="https://data.ene.iiasa.ac.at/auth" target="_blank">        separate license terms    </a> and the     <a href="https://iiasa.ac.at/web/terms-of-use.html" target="_blank">        IIASA Terms of Use    </a>.     Please review these terms carefully before using these data sets.</p>';
            app.GridLayout9 = uigridlayout(app.MainAppGridLayout);
            app.ResetpasswordButton = uibutton(app.GridLayout9, 'push');
            app.ResetpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @resetPassword, true);
            app.ResetpasswordButton.Text = 'Reset password';
            app.NewuserButton = uibutton(app.GridLayout9, 'push');
            app.NewuserButton.ButtonPushedFcn = createCallbackFcn(app, @registerUser, true);
            app.NewuserButton.Text = 'New user?';
            app.LoginButton = uibutton(app.GridLayout9, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginUser, true);
            app.LoginButton.Text = 'Login';
            app.GuestLoginButton = uibutton(app.GridLayout9, 'push');
            app.GuestLoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginGuest, true);
            app.GuestLoginButton.Text = 'Guest Login';
            app.LIMITSTab = uitab(app.TabGroup);
            app.LIMITSTab.Title = 'LIMITS';
            app.GridLayout10 = uigridlayout(app.LIMITSTab);
            app.HTML_2 = uihtml(app.GridLayout10);
            app.HTML_2.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">    Your use of the data sets provided by LIMITS is subjected to the <a href="https://tntcat.iiasa.ac.at/LIMITSDB/dsd?Action=htmlpage&page=about#cpy" target="_blank">         LIMITS copyright    </a>.     Please review these terms carefully before using this data sets.</p>';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.LimitsDB = uieditfield(app.GridLayout11, 'text');
            app.LimitsDB.Editable = 'off';
            app.SelectExcelFileButton = uibutton(app.GridLayout11, 'push');
            app.SelectExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFileButtonPushed, true);
            app.SelectExcelFileButton.Text = 'Select Excel File';
            app.LoginButton_2 = uibutton(app.GridLayout11, 'push');
            app.LoginButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_2Pushed, true);
            app.LoginButton_2.Text = 'Login';
            app.HTML2 = uihtml(app.GridLayout10);
            app.HTML2.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">In order to use the IAM Explorer with the limits database, you first need to download the database as a CSV file. You can do that <a href="https://tntcat.iiasa.ac.at/LIMITSPUBLICDB/dsd?Action=htmlpage&page=register1-3" target="_blank"> here</a>.</p>';
            app.WelcometotheLIMITSExplorerLabel = uilabel(app.LIMITSTab);
            app.WelcometotheLIMITSExplorerLabel.Text = 'Welcome to the LIMITS Explorer';
            app.BOCTab = uitab(app.TabGroup);
            app.BOCTab.Title = 'BOC';
            app.GridLayout12 = uigridlayout(app.BOCTab);
            app.WelcometotheBankofCanadaScenarioExplorerLabel = uilabel(app.GridLayout12);
            app.WelcometotheBankofCanadaScenarioExplorerLabel.Text = 'Welcome to the Bank of Canada Scenario Explorer';
            app.LoginButton_3 = uibutton(app.GridLayout12, 'push');
            app.LoginButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_3Pushed, true);
            app.LoginButton_3.Text = 'Login';
            app.OECMTab = uitab(app.TabGroup);
            app.OECMTab.Title = 'OECM';
            app.GridLayout12_2 = uigridlayout(app.OECMTab);
            app.WelcometotheOneEarthClimateModelExplorerLabel = uilabel(app.GridLayout12_2);
            app.WelcometotheOneEarthClimateModelExplorerLabel.Text = 'Welcome to the One Earth Climate Model Explorer';
            app.LoginButton_4 = uibutton(app.GridLayout12_2, 'push');
            app.LoginButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_4Pushed, true);
            app.LoginButton_4.Text = 'Login';
            % Show the figure after all components are created
            app.LoginPage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAMDataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginPage)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginPage)
        end
    end
end