classdef IAMDataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginPage                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IIASATab                        matlab.ui.container.Tab
        MainAppGridLayout               matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GuestLoginButton                matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        NewuserButton                   matlab.ui.control.Button
        ResetpasswordButton             matlab.ui.control.Button
        HTML                            matlab.ui.control.HTML
        GridLayout3                     matlab.ui.container.GridLayout
        DefaultDatabaseDropDown         matlab.ui.control.DropDown
        DefaultDatabaseDropDownLabel    matlab.ui.control.Label
        HTMLpassword                    matlab.ui.control.HTML
        PasswordLabel                   matlab.ui.control.Label
        HTMLusername                    matlab.ui.control.HTML
        UsernameLabel                   matlab.ui.control.Label
        WelcometotheIIASAExplorerLabel  matlab.ui.control.Label
        LIMITSTab                       matlab.ui.container.Tab
        WelcometotheLIMITSExplorerLabel  matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        HTML2                           matlab.ui.control.HTML
        GridLayout11                    matlab.ui.container.GridLayout
        LoginButton_2                   matlab.ui.control.StateButton
        SelectExcelFileButton           matlab.ui.control.Button
        LimitsDB                        matlab.ui.control.EditField
        HTML_2                          matlab.ui.control.HTML
    end

    
    properties (Access = private)
        AvailableEnvironments = ["ar60x2Dpublic";"ariadne";"cdlinks";"cmin";"commit";"deeds";"engage";"hotspots";"india_scenario_hub";"nexus0x2Dbasins";"ngfs";"ngfs_phase_3";"openentrance";"paris_lttg";"ripples";"senses";"setnav";"iamc15"; "limits"]; % Description
    end
    
    methods (Access = private)
        
        function login(app, e)

            if ~ismember(e.Connection.getEnvironmentConfig.env, app.AvailableEnvironments)
                str = strjoin(app.AvailableEnvironments,', ');
                delete(app)
                error("Please use the app only with the following selected enviroments: " + str)
            else
                iam.DataExplorer(e, app.AvailableEnvironments);
                delete(app)
            end
            
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DefaultDatabaseDropDown.Items = app.AvailableEnvironments;
            app.DefaultDatabaseDropDown.Value = 'ngfs_phase_3';
        end

        % Button pushed function: LoginButton
        function loginUser(app, event)
            d = uiprogressdlg(app.LoginPage,'Title','Connecting to database ...', 'Indeterminate','on');
            try
                c = iam.data.IIASAconnection(app.DefaultDatabaseDropDown.Value, app.HTMLusername.Data, app.HTMLpassword.Data ) ;
                % if login details are correct Authorize = true
                if c.Authorize
                    e = iam.IAMEnvironment(c);
                    close(d)
                    app.login(e);
                else
                    uialert(app.LoginPage, "Unauthorized", "Unauthorized")
                    close(d)
                end
            catch ME
                uialert(app.LoginPage, ME.message, "Something went wrong...")
                close(d)
            end

        end

        % Button pushed function: GuestLoginButton
        function loginGuest(app, event)
            d = uiprogressdlg(app.LoginPage,'Title','Connecting to database ...', 'Indeterminate','on');
            try
                c = iam.data.IIASAconnection(app.DefaultDatabaseDropDown.Value);
                e = iam.IAMEnvironment(c);
                app.login(e);
            catch
                uialert(app.LoginPage, 'Something went wrong, try with a different database. If the error persists, please make sure that you are running the latest version of the app.', 'Unable to import environment')
            end
            close(d)
        end

        % Button pushed function: NewuserButton
        function registerUser(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/register?application=IXSE_SR15', '-browser') ;
        end

        % Button pushed function: ResetpasswordButton
        function resetPassword(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/reset?application=IXSE_SR15','-browser') ;
        end

        % Callback function
        function showLogin(app, event)
            % value = app.showloginpageButton.Value;
            app.GridLayout.ColumnWidth = {'1x','0x'} ;
        end

        % Button pushed function: SelectExcelFileButton
        function SelectExcelFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv';'*.xls';'*.xlsx';'*.*'}, 'Please select file containing the LIMITS database');
            app.LimitsDB.Value = fullfile(path, file);            
        end

        % Value changed function: LoginButton_2
        function LoginButton_2ValueChanged(app, event)
            c = iam.data.LIMITSconnection(app.LimitsDB.Value);
            e = iam.IAMEnvironment(c);
            app.login(e);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginPage = uifigure('Visible', 'off');
            app.LoginPage.Name = 'IAM Login Page';
            app.TabGroup = uitabgroup(app.LoginPage);
            app.IIASATab = uitab(app.TabGroup);
            app.IIASATab.Title = 'IIASA';
            app.MainAppGridLayout = uigridlayout(app.IIASATab);
            app.WelcometotheIIASAExplorerLabel = uilabel(app.MainAppGridLayout);
            app.WelcometotheIIASAExplorerLabel.Text = 'Welcome to the IIASA Explorer';
            app.GridLayout3 = uigridlayout(app.MainAppGridLayout);
            app.UsernameLabel = uilabel(app.GridLayout3);
            app.UsernameLabel.Text = '  Username';
            app.HTMLusername = uihtml(app.GridLayout3);
            app.HTMLusername.HTMLSource = 'UsernameInput.html';
            app.PasswordLabel = uilabel(app.GridLayout3);
            app.PasswordLabel.Text = '  Password';
            app.HTMLpassword = uihtml(app.GridLayout3);
            app.HTMLpassword.HTMLSource = 'PasswordInput.html';
            app.DefaultDatabaseDropDownLabel = uilabel(app.GridLayout3);
            app.DefaultDatabaseDropDownLabel.Text = 'Default Database';
            app.DefaultDatabaseDropDown = uidropdown(app.GridLayout3);
            app.DefaultDatabaseDropDown.Items = {'cdlinks', 'hotspots', 'ngfs', 'paris_lttg', 'ripples', 'iamc15', 'openentrance'};
            app.DefaultDatabaseDropDown.Value = 'ngfs';
            app.HTML = uihtml(app.MainAppGridLayout);
            app.HTML.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">    Your use of the data sets provided by IIASA may be subject to     <a  href="https://data.ene.iiasa.ac.at/auth" target="_blank">        separate license terms    </a> and the     <a href="https://iiasa.ac.at/web/terms-of-use.html" target="_blank">        IIASA Terms of Use    </a>.     Please review these terms carefully before using these data sets.</p>';
            app.GridLayout9 = uigridlayout(app.MainAppGridLayout);
            app.ResetpasswordButton = uibutton(app.GridLayout9, 'push');
            app.ResetpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @resetPassword, true);
            app.ResetpasswordButton.Text = 'Reset password';
            app.NewuserButton = uibutton(app.GridLayout9, 'push');
            app.NewuserButton.ButtonPushedFcn = createCallbackFcn(app, @registerUser, true);
            app.NewuserButton.Text = 'New user?';
            app.LoginButton = uibutton(app.GridLayout9, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginUser, true);
            app.LoginButton.Text = 'Login';
            app.GuestLoginButton = uibutton(app.GridLayout9, 'push');
            app.GuestLoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginGuest, true);
            app.GuestLoginButton.Text = 'Guest Login';
            app.LIMITSTab = uitab(app.TabGroup);
            app.LIMITSTab.Title = 'LIMITS';
            app.GridLayout10 = uigridlayout(app.LIMITSTab);
            app.HTML_2 = uihtml(app.GridLayout10);
            app.HTML_2.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">    Your use of the data sets provided by LIMITS is subjected to the <a href="https://tntcat.iiasa.ac.at/LIMITSDB/dsd?Action=htmlpage&page=about#cpy" target="_blank">         LIMITS copyright    </a>.     Please review these terms carefully before using this data sets.</p>';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.LimitsDB = uieditfield(app.GridLayout11, 'text');
            app.LimitsDB.Editable = 'off';
            app.SelectExcelFileButton = uibutton(app.GridLayout11, 'push');
            app.SelectExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFileButtonPushed, true);
            app.SelectExcelFileButton.Text = 'Select Excel File';
            app.LoginButton_2 = uibutton(app.GridLayout11, 'state');
            app.LoginButton_2.ValueChangedFcn = createCallbackFcn(app, @LoginButton_2ValueChanged, true);
            app.LoginButton_2.Text = 'Login';
            app.HTML2 = uihtml(app.GridLayout10);
            app.HTML2.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">In order to use the IAM Explorer with the limits database, you first need to download the database as a CSV file. You can do that <a href="https://tntcat.iiasa.ac.at/LIMITSPUBLICDB/dsd?Action=htmlpage&page=register1-3" target="_blank"> here</a>.</p>';
            app.WelcometotheLIMITSExplorerLabel = uilabel(app.LIMITSTab);
            app.WelcometotheLIMITSExplorerLabel.Text = 'Welcome to the LIMITS Explorer';
            % Show the figure after all components are created
            app.LoginPage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAMDataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginPage)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginPage)
        end
    end
end