classdef IIASADataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginPage                       matlab.ui.Figure
        MainAppGridLayout               matlab.ui.container.GridLayout
        WelcometotheIIASAExplorerLabel  matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        UsernameLabel                   matlab.ui.control.Label
        HTMLusername                    matlab.ui.control.HTML
        PasswordLabel                   matlab.ui.control.Label
        HTMLpassword                    matlab.ui.control.HTML
        HTML                            matlab.ui.control.HTML
        GridLayout9                     matlab.ui.container.GridLayout
        ResetpasswordButton             matlab.ui.control.Button
        NewuserButton                   matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        GuestLoginButton                matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function login(app, e)
            
            iiasa.DataExplorer(e);
            delete(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function loginUser(app, event)
            try                
                c = iiasa.IIASAConnection('ngfs', app.HTMLusername.Data, app.HTMLpassword.Data ) ;                
                % if login details are correct Authorize = true
                if c.Authorize
                    e = iiasa.IIASAEnvironment(c);
                    app.login(e);
                end                
            catch ME
                uialert(app.LoginPage, ME.message, "Unauthorized")
            end
            
        end

        % Button pushed function: GuestLoginButton
        function loginGuest(app, event)
            c = iiasa.IIASAConnection('ngfs');
            e = iiasa.IIASAEnvironment(c);
            app.login(e);
        end

        % Button pushed function: NewuserButton
        function registerUser(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/register?application=IXSE_SR15', '-browser') ;
        end

        % Button pushed function: ResetpasswordButton
        function resetPassword(app, event)
            web('https://data.ene.iiasa.ac.at/auth/#/reset?application=IXSE_SR15','-browser') ;
        end

        % Callback function
        function showLogin(app, event)
            % value = app.showloginpageButton.Value;
            app.GridLayout.ColumnWidth = {'1x','0x'} ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginPage = uifigure('Visible', 'off');
            app.LoginPage.Name = 'IIASA Login Page';
            app.MainAppGridLayout = uigridlayout(app.LoginPage);
            app.WelcometotheIIASAExplorerLabel = uilabel(app.MainAppGridLayout);
            app.WelcometotheIIASAExplorerLabel.Text = 'Welcome to the IIASA Explorer';
            app.GridLayout3 = uigridlayout(app.MainAppGridLayout);
            app.UsernameLabel = uilabel(app.GridLayout3);
            app.UsernameLabel.Text = '  Username';
            app.HTMLusername = uihtml(app.GridLayout3);
            app.HTMLusername.HTMLSource = 'UsernameInput.html';
            app.PasswordLabel = uilabel(app.GridLayout3);
            app.PasswordLabel.Text = '  Password';
            app.HTMLpassword = uihtml(app.GridLayout3);
            app.HTMLpassword.HTMLSource = 'PasswordInput.html';
            app.HTML = uihtml(app.MainAppGridLayout);
            app.HTML.HTMLSource = '<p style="padding-left:10px;padding-top:50px;font-family:Helvetica Neue,Helvetica,Arial,sans-serif;font-size:18px;">    Your use of the data sets provided by IIASA may be subject to     <a  href="https://data.ene.iiasa.ac.at/auth" target="_blank">        separate license terms    </a> and the     <a href="https://iiasa.ac.at/web/terms-of-use.html" target="_blank">        IIASA Terms of Use    </a>.     Please review these terms carefully before using these data sets.</p>';
            app.GridLayout9 = uigridlayout(app.MainAppGridLayout);
            app.ResetpasswordButton = uibutton(app.GridLayout9, 'push');
            app.ResetpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @resetPassword, true);
            app.ResetpasswordButton.Text = 'Reset password';
            app.NewuserButton = uibutton(app.GridLayout9, 'push');
            app.NewuserButton.ButtonPushedFcn = createCallbackFcn(app, @registerUser, true);
            app.NewuserButton.Text = 'New user?';
            app.LoginButton = uibutton(app.GridLayout9, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginUser, true);
            app.LoginButton.Text = 'Login';
            app.GuestLoginButton = uibutton(app.GridLayout9, 'push');
            app.GuestLoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginGuest, true);
            app.GuestLoginButton.Text = 'Guest Login';
            % Show the figure after all components are created
            app.LoginPage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IIASADataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginPage)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginPage)
        end
    end
end